classdef FinalProject_GUI_12_1_24 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GuestButton           matlab.ui.control.Button
        HostButton            matlab.ui.control.Button
        DidyouwinLabel        matlab.ui.control.Label
        NextHandButton        matlab.ui.control.Button
        tablecardsLabel       matlab.ui.control.Label
        TableCardsLabel       matlab.ui.control.Label
        YourHandCardsLabel    matlab.ui.control.Label
        BalanceamntLabel      matlab.ui.control.Label
        potamntLabel          matlab.ui.control.Label
        PotLabel              matlab.ui.control.Label
        amntLabel_2           matlab.ui.control.Label
        RaisedAmountLabel_4   matlab.ui.control.Label
        amntLabel             matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        PokerLabel            matlab.ui.control.Label
        Player3Label          matlab.ui.control.Label
        RaisedAmountLabel     matlab.ui.control.Label
        Player2Label          matlab.ui.control.Label
        AmountEditField       matlab.ui.control.NumericEditField
        AmountEditFieldLabel  matlab.ui.control.Label
        RaiseButton           matlab.ui.control.Button
        CallButton            matlab.ui.control.Button
        FoldButton            matlab.ui.control.Button
    end

    %variables to keep track of each players winnings and the pot
    properties (Access = private)
        p1Bal double = 0;
        p2Bal double = 0;
        p3Bal double = 0;
        potBal double = 0;
        
        p1Cards = [0,0;0,0];
        p2Cards = [0;0]; % ignore
        p3Cards = [0,0;0,0];
        tableCards = ["0", "0", "0", "0", "0"; "S", "S", "S", "S", "S"];
        
        roundNum = 0;
        channelID = 2776676;
        writeKey = "CYR8YQRIZD2BA8P8";
        readKey = "JH3LIBW6JC1LS6SS";

        isHost = 0;
        isGuest = 0;
        % -1 = fold, 0 = calling, > 0 = raise, -2 = default
        p1action = -2;
        p1_folded = false;
        p2_folded = false;
    end

    methods (Access = private)
        % Updates the labels for balances and the pot dynamically
        function updateLabels(app)
            % Update the pot label
            app.potamntLabel.Text = sprintf('$ %.2f', app.potBal);

            % Update each player's balance labels
            app.BalanceamntLabel.Text = sprintf('$ Balance: %.2f', app.p1Bal);

            % Print card values to screen
            app.YourHandCardsLabel.Text = "Your Hand: " + app.p1Cards(1,1) + app.p1Cards(2,1) + " " + app.p1Cards(1,2) + app.p1Cards(2,2);
            tmp = app.tableCards;
            tmp(3, :) = " ";
            disp(app.tableCards)
            cardString = strjoin(tmp(1:end - 1)');
            app.tablecardsLabel.Text = cardString;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitVal(app)
            % Initialize balances and pot
            app.p1Bal = 1000; % Set initial balance for Player 1
            app.p2Bal = 1000; % Set initial balance for Player 2
            app.p3Bal = 1000; % Set initial balance for Player 3
            app.potBal = 0;   % Set initial pot values

            % Update the labels to reflect initial values
            updateLabels(app);
            % Code that executes after component creati
            % Wait until decided if guest or host
            while ~xor(app.isGuest, app.isHost)
                pause(1);
            end

            %there should be a function here to read the card values
           
            disp("selected")
            if app.isHost
                player1Eval = 0;
                player2Eval = 0;
                deck = Deck();
                deck = deck.shuffled();
                
                % Deal out 2 cards             per player
                % Player 1
                [app.p1Cards, deck] = deck.dealn(2);
                disp(app.p1Cards);
    
                % Player 2
                [app.p2Cards, deck] = deck.dealn(2);
                disp(app.p2Cards);


                % Player 3
                [app.p3Cards, deck] = deck.dealn(2);
                disp(app.p3Cards);
                
                updateLabels(app);
                
                % compute table cards, but keep hidden
                [hidden_table, deck] = deck.dealn(3);

                % write current state
                %{
                thingSpeakWrite(app.channelID, Fields=1, Values="", WriteKey=app.writeKey); % p2_cards
                pause(15);
                thingSpeakWrite(app.channelID, Fields=2, Values=-2, WriteKey=app.writeKey); % p1_action
                pause(15);
                thingSpeakWrite(app.channelID, Fields=3, Values=-2, WriteKey=app.writeKey); % p2_action
                pause(15);
                thingSpeakWrite(app.channelID, Fields=4, Values=-1, WriteKey=app.writeKey); %p2_win, -1 for not updated
                pause(15);
                thingSpeakWrite(app.channelID, Fields=5, Values=app.p2Bal, WriteKey=app.writeKey); % p2_bal
                pause(15);
                thingSpeakWrite(app.channelID, Fields=6, Values=0, WriteKey=app.writeKey); % p2_ready
                pause(15);
                thingSpeakWrite(app.channelID, Fields=7, Values=0, WriteKey=app.writeKey); % game_over
                pause(15);
                thingSpeakWrite(app.channelID, Fields=8, Values="", WriteKey=app.writeKey); % table_cards
                pause(15);
                %}
                uialert(app.UIFigure, 'We are setting up the server, this could take ~1 minute. We will notify you when its done. Do not do anything or have a guest join prior.', 'Info');

                state = {char(Deck.hand2str(app.p2Cards)), -2, -2, -1, app.p2Bal, 0, 0, char(Deck.hand2str(hidden_table))};

                thingSpeakWrite(app.channelID, WriteKey=app.writeKey, Fields=[1, 2, 3, 4, 5, 6, 7, 8], Values=state);
                pause(15);

                uialert(app.UIFigure, 'We have sucessfully set up your server, you can have a guest join now', 'Success');

                p2action = -2;
                while (app.p1action == -2)
                    pause(1);
                end
                
                disp("p1 made action");

                odds1 = Eval.mcs(app.p3Cards, app.p1Cards, 100);
                odds2 = Eval.mcs(app.p3Cards, app.p2Cards, 100);

                disp("p3 ran 1000 monte carlo simulations")
                if odds1 > 0.5 && odds2 > 0.5
                    disp("p3 decides to raise")
                    app.amntLabel_2.Text = string(100);
                else
                    disp("p3 decides not to raise")
                    app.amntLabel_2.Text = string(0);
                end

                res = thingSpeakRead(app.channelID, ReadKey=app.readKey, OutputFormat="table");
                res{1, "p1_action"} = app.p1action;
                res = removevars(res, "Timestamps");

                thingSpeakWrite(app.channelID, res, WriteKey=app.writeKey);
                pause(15);

                disp("sent action");

                app.tableCards = hidden_table;
                updateLabels(app);
                
                while(p2action == -2)
                    res = thingSpeakRead(app.channelID, Fields=3, ReadKey=app.readKey, OutputFormat="table");
                    p2action = res{1, "p2_action"};
                    pause(5);
                end

                p2action
                disp("Received action")

                % deal with actions
                if (p2action == -1)
                    uialert(app.UIFigure, 'Your opponent has folded', 'You Won!');
                    app.p2_folded = true;

                elseif (p2action == 0)
                        app.p2Bal = app.p2Bal - raiseAmount; % Deduct from player's balance
                        app.potBal = app.potBal + raiseAmount; % Add to the pot
                        updateLabels(app); % Update labels
                        thingSpeakWrite(app.channelID, Fields=4, Values=raiseAmount, WriteKey=app.writeKey)
                end

                if (~app.p2_folded && ~app.p1_folded)
                    app.tableCards = hidden_table;
                    updateLabels(app);

                    app.p1action = -2;
                    p2action = -2;


                    while (app.p1action == -2 || p2action == -2)
                        res = thingSpeakRead(app.channelID, Fields=3, ReadKey=app.readKey, OutputFormat="table");
                        p2action = res{1, 2};
                        pause(5);
                    end

                    if (p2action == -1)
                        uialert(app.UIFigure, 'Your opponent has folded', 'You Won!');

                    elseif (p2action >= 1)
                        uialert(app.UIFigure, 'Your opponent has raised', 'Info');
                        app.p2Bal = app.p2Bal - p2action; % Deduct from player's balance
                        app.potBal = app.potBal + p2action; % Add to the pot
                        updateLabels(app); % Update labels
                    end
                    % eval cards and decide winner
                    player1EvalArray = [app.p1Cards, app.tableCards];
                    player1Eval = Eval.eval5(player1EvalArray);
                    player2EvalArray = [app.p2Cards, app.tableCards];
                    player2Eval = Eval.eval5(player2EvalArray);
                end


                % Compare and decide winner
                didYouWin = (app.p1action ~= -1 && player1Eval > player2Eval) || app.p2_folded;
                didYouWin = didYouWin && ~app.p1_folded;
                if didYouWin
                    uialert(app.UIFigure, 'You Won!', 'Info');
                else
                    uialert(app.UIFigure, 'You lost!', 'Info');
                end

                % upadte p2winner on thingspeak
                p2winner = ~didYouWin;
                pause(15);
                thingSpeakWrite(app.channelID, Fields=4, Values=p2winner, WriteKey=app.writeKey);
                pause(15);

                if (p2winner)
                    app.p2Bal = app.p2Bal + app.potBal;
                    thingSpeakWrite(app.channelID, Fields=5, Values=app.p2Bal, WriteKey=app.writeKey);
                else
                    app.p1Bal = app.p1Bal + app.potBal;
                end

            else
                % handle player 2, note that local to player 2, we deal
                % with cards as p1
                % push that player 2 is ready
                % fetch cards and load them in
                res = thingSpeakRead(app.channelID, ReadKey=app.readKey, OutputFormat="table");
                app.p1Cards = Deck.str2hand(string(res{1, 2}));
                app.p1Bal = res{1, "p2_bal"};
                updateLabels(app);
                
                res{1, "p2_ready"} = 1;
                res = removevars(res, "Timestamps");

                %pause(15);
                %thingSpeakWrite(app.channelID, res, WriteKey=app.writeKey);
                %pause(15);


                % note that p2 is relative to the guest -- waiting for the host
                p2_action = -2;
                while (p2_action == -2)
                    res = thingSpeakRead(app.channelID, ReadKey=app.readKey, OutputFormat="table");
                    p2_action = res{1, "p1_action"};
                    pause(5);
                end
                p2_action
                if (p2_action == -1)
                    uialert(app.UIFigure, 'Your Opponent has folded!.', 'Info'); % Display error
                    app.p2_folded = true;
                else
                    app.p2Bal = app.p2Bal - p2_action; % Deduct from player's balance
                    app.potBal = app.potBal + p2_action; % Add to the pot
                    app.amntLabel.Text = string(p2_action);
                    updateLabels(app); % Update labels
                end
                if (~app.p2_folded)
                    res = thingSpeakRead(app.channelID, ReadKey=app.readKey, OutputFormat="table");
                    app.tableCards = Deck.str2hand(string(res{1, "table_cards"}));
                    updateLabels(app);
                    while (app.p1action == -2)
                        pause(1);
                    end


                    % we've made a move check if they folded
                    if (p2_action == -1)
                        uialert(app.UIFigure, 'Your Opponent has folded!.', 'Info'); % Display error
                        app.p1Bal = app.p1Bal + app.potBal;
                    end

                    while (p2_action == -2)
                        pause(1);
                    end
                end
                thingSpeakWrite(app.channelID, Fields=3, Values=app.p1action, WriteKey=app.writeKey);
                pause(15);
                won = -1;
                while (won == -1)
                    res = thingSpeakRead(app.channelID, Fields=1, ReadKey=app.readKey, OutputFormat="table");
                    won = res{1, 2};
                    pause(5);
                end
                if (won == true)
                    uialert(app.UIFigure, 'You win!', 'Info');
                end

            end


        end

        % Button pushed function: FoldButton
        function FoldButtonPushed(app, event)
            app.p1action = -1;
            app.p1_folded = true;
            %this should end the round for the player
            % Push to p2_won
        end

        % Button pushed function: CallButton
        function CallButtonPushed(app, event)
            %{
            callAmount = app.AmountEditField.Value; % Example call amount
            currentPlayerBalance = app.p1Bal; % Example for Player 1 (can be dynamic)
        
            if currentPlayerBalance >= callAmount
                app.p1Bal = app.p1Bal - callAmount; % Deduct call amount from balance
                app.potBal = app.potBal + callAmount; % Add to the pot
                updateLabels(app); % Update labels
                app.p1action = 0;
            else
                uialert(app.UIFigure, 'Insufficient balance to call.', 'Error'); % Display error
            end
            %}
        end

        % Button pushed function: RaiseButton
        function RaiseButtonPushed(app, event)

            raiseAmount = app.AmountEditField.Value; % Get raise amount
            currentPlayerBalance = app.p1Bal; % Example for Player 1 (can be dynamic)
        
            if currentPlayerBalance >= raiseAmount && raiseAmount >=0
                app.p1Bal = app.p1Bal - raiseAmount; % Deduct from player's balance
                app.potBal = app.potBal + raiseAmount; % Add to the pot
                updateLabels(app); % Update labels
                app.p1action = raiseAmount;
            else
                uialert(app.UIFigure, 'Insufficient balance to raise.', 'Error'); % Display error
            end
        end

        % Button pushed function: NextHandButton
        function NextHandButtonPushed(app, event)
            %need function to start the next hand
            %has to keep everyones balance but empty the pot
            %and deal new cards
        end

        % Button pushed function: HostButton
        function HostButtonPushed(app, event)
            app.isHost = 1;
            app.HostButton.BackgroundColor = [1, 0, 0];
        end

        % Button pushed function: GuestButton
        function GuestButtonPushed(app, event)
            app.isGuest = 1;
            app.GuestButton.BackgroundColor = [1, 0, 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FoldButton = uibutton(app.UIFigure, 'push');
            app.FoldButton.ButtonPushedFcn = createCallbackFcn(app, @FoldButtonPushed, true);
            app.FoldButton.Text = 'Fold';
            app.CallButton = uibutton(app.UIFigure, 'push');
            app.CallButton.ButtonPushedFcn = createCallbackFcn(app, @CallButtonPushed, true);
            app.CallButton.Text = 'Call';
            app.RaiseButton = uibutton(app.UIFigure, 'push');
            app.RaiseButton.ButtonPushedFcn = createCallbackFcn(app, @RaiseButtonPushed, true);
            app.RaiseButton.Text = 'Raise';
            app.AmountEditFieldLabel = uilabel(app.UIFigure);
            app.AmountEditFieldLabel.Text = '$ Amount';
            app.AmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountEditField.Value = 352;
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.RaisedAmountLabel = uilabel(app.UIFigure);
            app.RaisedAmountLabel.Text = '$ Raised  Amount:';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.PokerLabel = uilabel(app.UIFigure);
            app.PokerLabel.Text = 'Poker';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '360_F_338379003_37giLQjJMMI3uFS2EFAQkzHr7QyaCS26-removebg-preview.png');
            app.amntLabel = uilabel(app.UIFigure);
            app.amntLabel.Text = '{amnt}';
            app.RaisedAmountLabel_4 = uilabel(app.UIFigure);
            app.RaisedAmountLabel_4.Text = '$ Raised  Amount:';
            app.amntLabel_2 = uilabel(app.UIFigure);
            app.amntLabel_2.Text = '{amnt}';
            app.PotLabel = uilabel(app.UIFigure);
            app.PotLabel.Text = '$ Pot:';
            app.potamntLabel = uilabel(app.UIFigure);
            app.potamntLabel.Text = '{pot amnt}';
            app.BalanceamntLabel = uilabel(app.UIFigure);
            app.BalanceamntLabel.Text = '$ Balance: {amnt}';
            app.YourHandCardsLabel = uilabel(app.UIFigure);
            app.YourHandCardsLabel.Text = 'Your Hand: {Cards}';
            app.TableCardsLabel = uilabel(app.UIFigure);
            app.TableCardsLabel.Text = 'Table Cards:';
            app.tablecardsLabel = uilabel(app.UIFigure);
            app.tablecardsLabel.Text = '{table cards}';
            app.NextHandButton = uibutton(app.UIFigure, 'push');
            app.NextHandButton.ButtonPushedFcn = createCallbackFcn(app, @NextHandButtonPushed, true);
            app.NextHandButton.Text = 'Next Hand';
            app.DidyouwinLabel = uilabel(app.UIFigure);
            app.DidyouwinLabel.Text = 'Did you win?';
            app.HostButton = uibutton(app.UIFigure, 'push');
            app.HostButton.ButtonPushedFcn = createCallbackFcn(app, @HostButtonPushed, true);
            app.HostButton.Text = 'Host';
            app.GuestButton = uibutton(app.UIFigure, 'push');
            app.GuestButton.ButtonPushedFcn = createCallbackFcn(app, @GuestButtonPushed, true);
            app.GuestButton.Text = 'Guest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject_GUI_12_1_24

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitVal)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end